classdef Cylinder_1D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DCylinderrdirectionUIFigure    matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        RadiusmEditFieldLabel          matlab.ui.control.Label
        RadiusmEditField               matlab.ui.control.NumericEditField
        HeightmEditFieldLabel          matlab.ui.control.Label
        HeightmEditField               matlab.ui.control.NumericEditField
        AssociationConstKMhrEditFieldLabel  matlab.ui.control.Label
        AssociationConstKMhrEditField  matlab.ui.control.NumericEditField
        DissociationConstk21hrEditFieldLabel  matlab.ui.control.Label
        DissociationConstk21hrEditField  matlab.ui.control.NumericEditField
        DiffusionCoeffDm2hrEditFieldLabel  matlab.ui.control.Label
        DiffusionCoeffDm2hrEditField   matlab.ui.control.NumericEditField
        DrugMEditFieldLabel            matlab.ui.control.Label
        DrugMEditField                 matlab.ui.control.NumericEditField
        CyclodextrinMEditFieldLabel    matlab.ui.control.Label
        CyclodextrinMEditField         matlab.ui.control.NumericEditField
        DurationhrEditFieldLabel       matlab.ui.control.Label
        DurationhrEditField            matlab.ui.control.NumericEditField
        ResolutionSpinnerLabel         matlab.ui.control.Label
        ResolutionSpinner              matlab.ui.control.Spinner
        InputParametersLabel           matlab.ui.control.Label
        FiguresLabel                   matlab.ui.control.Label
        DirectionalReleaseProfileheatmapCheckBox  matlab.ui.control.CheckBox
        ReleaseProfileAnimationCheckBox  matlab.ui.control.CheckBox
        ChangeinFractionalReleaseProfileCheckBox  matlab.ui.control.CheckBox
        UploadDataCheckBox             matlab.ui.control.CheckBox
        RunButton                      matlab.ui.control.StateButton
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        OutputTextAreaLabel            matlab.ui.control.Label
        OutputTextArea                 matlab.ui.control.TextArea
        CumulativeReleaseProfileCheckBox  matlab.ui.control.CheckBox
        FractionalReleaseProfileCheckBox  matlab.ui.control.CheckBox
        ChangeinReleaseRateCheckBox    matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % Declare all variables that need to be passed between functions
        % Inputs
        Drug_0 % 
        CD_0    % Initial [CD]
        K       % Equilibrium constant
        k2      % Dissociation rate constant
        D       % Diffusion coefficient (constant)
        hrs     % Duration
        res     % Resolution
        Figure_1
        Figure_2
        Figure_3
        Figure_4
        Figure_5
        Animation
        r       % Radius
        h       % Height
        UploadData % User-defined data for comparison with model
        % Outputs
        time
        release % Release data
        output
        text
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DrugMEditField
        function DrugMEditFieldValueChanged(app, event)
            % If the variable's value is changed, save the new value
            app.Drug_0 = app.DrugMEditField.Value;
        end

        % Value changed function: CyclodextrinMEditField
        function CyclodextrinMEditFieldValueChanged(app, event)
            app.CD_0 = app.CyclodextrinMEditField.Value;
        end

        % Value changed function: AssociationConstKMhrEditField
        function AssociationConstKMhrEditFieldValueChanged(app, event)
            app.K = app.AssociationConstKMhrEditField.Value;
        end

        % Value changed function: DissociationConstk21hrEditField
        function DissociationConstk21hrEditFieldValueChanged(app, event)
            app.k2 = app.DissociationConstk21hrEditField.Value;
        end

        % Value changed function: DiffusionCoeffDm2hrEditField
        function DiffusionCoeffDm2hrEditFieldValueChanged(app, event)
            app.D = app.DiffusionCoeffDm2hrEditField.Value;  
        end

        % Value changed function: DurationhrEditField
        function DurationhrEditFieldValueChanged(app, event)
            app.hrs = app.DurationhrEditField.Value;
        end

        % Value changed function: ResolutionSpinner
        function ResolutionSpinnerValueChanged(app, event)
            app.res = app.ResolutionSpinner.Value; 
        end

        % Value changed function: CumulativeReleaseProfileCheckBox
        function CumulativeReleaseProfileCheckBoxValueChanged(app, event)
            app.Figure_1 = app.CumulativeReleaseProfileCheckBox.Value;   
        end

        % Value changed function: FractionalReleaseProfileCheckBox
        function FractionalReleaseProfileCheckBoxValueChanged(app, event)
            app.Figure_2 = app.FractionalReleaseProfileCheckBox.Value;    
        end

        % Value changed function: ChangeinReleaseRateCheckBox
        function ChangeinReleaseRateCheckBoxValueChanged(app, event)
            app.Figure_3 = app.ChangeinReleaseRateCheckBox.Value;   
        end

        % Value changed function: 
        % ChangeinFractionalReleaseProfileCheckBox
        function ChangeinFractionalReleaseProfileCheckBoxValueChanged(app, event)
            app.Figure_4 = app.ChangeinFractionalReleaseProfileCheckBox.Value;  
        end

        % Value changed function: 
        % DirectionalReleaseProfileheatmapCheckBox
        function DirectionalReleaseProfileheatmapCheckBoxValueChanged(app, event)
            app.Figure_5 = app.DirectionalReleaseProfileheatmapCheckBox.Value;     
        end

        % Value changed function: ReleaseProfileAnimationCheckBox
        function ReleaseProfileAnimationCheckBoxValueChanged(app, event)
            app.Animation = app.ReleaseProfileAnimationCheckBox.Value;
        end

        % Value changed function: RadiusmEditField
        function RadiusmEditFieldValueChanged(app, event)
            app.r = app.RadiusmEditField.Value; 
        end

        % Value changed function: HeightmEditField
        function HeightmEditFieldValueChanged(app, event)
            app.h = app.HeightmEditField.Value;   
        end

        % Value changed function: UploadDataCheckBox
        function UploadDataCheckBoxValueChanged(app, event)
            app.UploadData = app.UploadDataCheckBox.Value;  
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
        % If any variables are not changed, use default values
            if numel(app.Drug_0) ~= 1
                app.Drug_0 = 0.2;
            end
            
            if numel(app.CD_0) ~= 1
                app.CD_0 = 0.2;
            end         
            
            if numel(app.K) ~= 1
                app.K = 1.5;
            end      

            if numel(app.k2) ~= 1
                app.k2 = 0.8;
            end  
            
            if numel(app.D) ~= 1
                app.D = 2e-7;
            end  
            
            if numel(app.hrs) ~= 1
                app.hrs = 30;
            end
            
            if numel(app.res) ~= 1
                app.res = 100;
            end
   
            if numel(app.r) ~= 1
                app.r = 0.001;
            end
            
            if numel(app.h) ~= 1
                app.h = 0.01;
            end
            
            % Default values for control of progression
            choice = 1;
            choice_2 = 1;
            
            % If the radius-to-height ratio < 10 --> prompt warning
            if app.h < 10 * app.r
               answer = questdlg('The 1D Cylinder simulation assumes a radius-to-height ratio of 1:10. Any other relationship can result in less accurate modelings. Do you wish to continue?',...
                    'Warning','OK','Cancel','Cancel');

                % Handle response
                switch answer
                    case 'OK'
                        choice = 1;
                    case 'Cancel'
                        choice = 0;
                end
            end 
            
            % If the simulation resolution < 50 --> prompt warning
            if app.res < 50
               answer = questdlg('Resolutions below 50 may result in less accurate results (i.e. not all drug being released). Do you wish to continue?',...
                    'Warning','OK','Cancel','Cancel');

                % Handle response
                switch answer
                    case 'OK'
                        choice_2 = 1;
                    case 'Cancel'
                        choice_2 = 0;
                end
            end
            
            % If the user wants to see the animation and the resolution < 2
            %  --> prompt warning
            if numel(app.Animation) == 1 && app.res < 2
                warndlg('A resolution of 2 or higher is required for this feature',...
                    'Warning');
                app.Animation = [];
            end
            
            % Only run the app if the user presses 'OK' on warnings 
            if choice == 1 && choice_2 == 1
                % Plug parametere values into MoL_Script 
                [app.time,app.release,app.output] = MoL_Script_1D_Cyl(app.r,...
                    app.h,app.Drug_0,app.CD_0,app.K,app.k2,app.D,app.hrs,...
                    app.res,app.Figure_1,app.Figure_2,app.Figure_3,...
                    app.Figure_4,app.Figure_5,app.Animation,app.UploadData);
               
                % Plot cumulative release profile
                plot(app.UIAxes, app.time, app.release)  
                % Return output text
                app.OutputTextArea.Value = app.output;
            end 
        end

        % Callback function
        function DCylinderrdirectionUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DCylinderrdirectionUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {379, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DCylinderrdirectionUIFigure = uifigure('Visible', 'off');
            app.DCylinderrdirectionUIFigure.Name = '1D Cylinder (r - direction)';
            app.DCylinderrdirectionUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DCylinderrdirectionUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RadiusmEditFieldLabel = uilabel(app.LeftPanel);
            app.RadiusmEditFieldLabel.Text = 'Radius (m)';
            app.RadiusmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RadiusmEditField.Limits = [0 Inf];
            app.RadiusmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusmEditFieldValueChanged, true);
            app.RadiusmEditField.Value = 0.001;
            app.HeightmEditFieldLabel = uilabel(app.LeftPanel);
            app.HeightmEditFieldLabel.Text = 'Height (m)';
            app.HeightmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HeightmEditField.Limits = [0 Inf];
            app.HeightmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmEditFieldValueChanged, true);
            app.HeightmEditField.Value = 0.01;
            app.AssociationConstKMhrEditFieldLabel = uilabel(app.LeftPanel);
            app.AssociationConstKMhrEditFieldLabel.Text = 'Association Const (K, 1/M)';
            app.AssociationConstKMhrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AssociationConstKMhrEditField.Limits = [0 Inf];
            app.AssociationConstKMhrEditField.ValueChangedFcn = createCallbackFcn(app, @AssociationConstKMhrEditFieldValueChanged, true);
            app.AssociationConstKMhrEditField.Value = 1.5;
            app.DissociationConstk21hrEditFieldLabel = uilabel(app.LeftPanel);
            app.DissociationConstk21hrEditFieldLabel.Text = 'Dissociation Const (k2, 1/hr)';
            app.DissociationConstk21hrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DissociationConstk21hrEditField.Limits = [0 Inf];
            app.DissociationConstk21hrEditField.ValueChangedFcn = createCallbackFcn(app, @DissociationConstk21hrEditFieldValueChanged, true);
            app.DissociationConstk21hrEditField.Value = 0.8;
            app.DiffusionCoeffDm2hrEditFieldLabel = uilabel(app.LeftPanel);
            app.DiffusionCoeffDm2hrEditFieldLabel.Text = 'Diffusion Coeff (D, m^2/hr)';
            app.DiffusionCoeffDm2hrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DiffusionCoeffDm2hrEditField.Limits = [0 Inf];
            app.DiffusionCoeffDm2hrEditField.ValueChangedFcn = createCallbackFcn(app, @DiffusionCoeffDm2hrEditFieldValueChanged, true);
            app.DiffusionCoeffDm2hrEditField.Value = 2e-07;
            app.DrugMEditFieldLabel = uilabel(app.LeftPanel);
            app.DrugMEditFieldLabel.Text = '[Drug] (M)';
            app.DrugMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DrugMEditField.Limits = [0 Inf];
            app.DrugMEditField.ValueChangedFcn = createCallbackFcn(app, @DrugMEditFieldValueChanged, true);
            app.DrugMEditField.Value = 0.2;
            app.CyclodextrinMEditFieldLabel = uilabel(app.LeftPanel);
            app.CyclodextrinMEditFieldLabel.Text = '[Cyclodextrin] (M)';
            app.CyclodextrinMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CyclodextrinMEditField.Limits = [0 Inf];
            app.CyclodextrinMEditField.ValueChangedFcn = createCallbackFcn(app, @CyclodextrinMEditFieldValueChanged, true);
            app.CyclodextrinMEditField.Value = 0.2;
            app.DurationhrEditFieldLabel = uilabel(app.LeftPanel);
            app.DurationhrEditFieldLabel.Text = 'Duration (hr)';
            app.DurationhrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DurationhrEditField.Limits = [0 Inf];
            app.DurationhrEditField.ValueChangedFcn = createCallbackFcn(app, @DurationhrEditFieldValueChanged, true);
            app.DurationhrEditField.Value = 30;
            app.ResolutionSpinnerLabel = uilabel(app.LeftPanel);
            app.ResolutionSpinnerLabel.Text = 'Resolution';
            app.ResolutionSpinner = uispinner(app.LeftPanel);
            app.ResolutionSpinner.Limits = [0 Inf];
            app.ResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @ResolutionSpinnerValueChanged, true);
            app.ResolutionSpinner.Value = 100;
            app.InputParametersLabel = uilabel(app.LeftPanel);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.FiguresLabel = uilabel(app.LeftPanel);
            app.FiguresLabel.Text = 'Figures';
            app.DirectionalReleaseProfileheatmapCheckBox = uicheckbox(app.LeftPanel);
            app.DirectionalReleaseProfileheatmapCheckBox.ValueChangedFcn = createCallbackFcn(app, @DirectionalReleaseProfileheatmapCheckBoxValueChanged, true);
            app.DirectionalReleaseProfileheatmapCheckBox.Text = 'Directional Release Profile (heatmap) ';
            app.ReleaseProfileAnimationCheckBox = uicheckbox(app.LeftPanel);
            app.ReleaseProfileAnimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReleaseProfileAnimationCheckBoxValueChanged, true);
            app.ReleaseProfileAnimationCheckBox.Text = 'Release Profile Animation';
            app.ChangeinFractionalReleaseProfileCheckBox = uicheckbox(app.LeftPanel);
            app.ChangeinFractionalReleaseProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeinFractionalReleaseProfileCheckBoxValueChanged, true);
            app.ChangeinFractionalReleaseProfileCheckBox.Text = 'Change in Fractional Release Profile';
            app.UploadDataCheckBox = uicheckbox(app.LeftPanel);
            app.UploadDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @UploadDataCheckBoxValueChanged, true);
            app.UploadDataCheckBox.Text = 'Upload Data';
            app.RunButton = uibutton(app.LeftPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Cumulative Release Profile')
            xlabel(app.UIAxes, 'Time (hr)')
            ylabel(app.UIAxes, 'Molar Drug (M)')
            app.OutputTextAreaLabel = uilabel(app.RightPanel);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.RightPanel);
            app.CumulativeReleaseProfileCheckBox = uicheckbox(app.DCylinderrdirectionUIFigure);
            app.CumulativeReleaseProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @CumulativeReleaseProfileCheckBoxValueChanged, true);
            app.CumulativeReleaseProfileCheckBox.Text = 'Cumulative Release Profile';
            app.FractionalReleaseProfileCheckBox = uicheckbox(app.DCylinderrdirectionUIFigure);
            app.FractionalReleaseProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @FractionalReleaseProfileCheckBoxValueChanged, true);
            app.FractionalReleaseProfileCheckBox.Text = 'Fractional Release Profile';
            app.ChangeinReleaseRateCheckBox = uicheckbox(app.DCylinderrdirectionUIFigure);
            app.ChangeinReleaseRateCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeinReleaseRateCheckBoxValueChanged, true);
            app.ChangeinReleaseRateCheckBox.Text = 'Change in Release Rate';
            % Show the figure after all components are created
            app.DCylinderrdirectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cylinder_1D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DCylinderrdirectionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DCylinderrdirectionUIFigure)
        end
    end
end