classdef Prism_3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DPrismxyzdirectionsUIFigure    matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        LengthxmEditFieldLabel         matlab.ui.control.Label
        LengthxmEditField              matlab.ui.control.NumericEditField
        AssociationConstKMhrEditFieldLabel  matlab.ui.control.Label
        AssociationConstKMhrEditField  matlab.ui.control.NumericEditField
        DissociationConstk21hrEditFieldLabel  matlab.ui.control.Label
        DissociationConstk21hrEditField  matlab.ui.control.NumericEditField
        DiffusionCoeffDm2hrEditFieldLabel  matlab.ui.control.Label
        DiffusionCoeffDm2hrEditField   matlab.ui.control.NumericEditField
        DrugMEditFieldLabel            matlab.ui.control.Label
        DrugMEditField                 matlab.ui.control.NumericEditField
        CyclodextrinMEditFieldLabel    matlab.ui.control.Label
        CyclodextrinMEditField         matlab.ui.control.NumericEditField
        DurationhrEditFieldLabel       matlab.ui.control.Label
        DurationhrEditField            matlab.ui.control.NumericEditField
        ResolutionSpinnerLabel         matlab.ui.control.Label
        ResolutionSpinner              matlab.ui.control.Spinner
        InputParametersLabel           matlab.ui.control.Label
        FiguresLabel                   matlab.ui.control.Label
        DirectionalReleaseProfileheatmapCheckBox  matlab.ui.control.CheckBox
        ReleaseProfileAnimationCheckBox  matlab.ui.control.CheckBox
        ChangeinFractionalReleaseProfileCheckBox  matlab.ui.control.CheckBox
        UploadDataCheckBox             matlab.ui.control.CheckBox
        RunButton                      matlab.ui.control.StateButton
        HeightzmEditFieldLabel         matlab.ui.control.Label
        HeightzmEditField              matlab.ui.control.NumericEditField
        WidthymEditFieldLabel          matlab.ui.control.Label
        WidthymEditField               matlab.ui.control.NumericEditField
        DGradientsCheckBox             matlab.ui.control.CheckBox
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        OutputTextAreaLabel            matlab.ui.control.Label
        OutputTextArea                 matlab.ui.control.TextArea
        CumulativeReleaseProfileCheckBox  matlab.ui.control.CheckBox
        FractionalReleaseProfileCheckBox  matlab.ui.control.CheckBox
        ChangeinReleaseRateCheckBox    matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % Declare all variables that need to be passed between functions
        % Inputs
        Drug_0 % 
        CD_0    % Initial [CD]
        K       % Equilibrium constant
        k2      % Dissociation rate constant
        D       % Diffusion coefficient (constant)
        hrs     % Duration
        res     % Resolution
        Figure_1
        Figure_2
        Figure_3
        Figure_4
        Figure_5
        Figure_6
        Animation
        X       % Length
        Y       % Width
        Z       % Height
        UploadData % User-defined data for comparison with model
        % Outputs
        time
        release % Release data
        output
        text
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DrugMEditField
        function DrugMEditFieldValueChanged(app, event)
            % If the variable's value is changed, save the new value
            app.Drug_0 = app.DrugMEditField.Value;
        end

        % Value changed function: CyclodextrinMEditField
        function CyclodextrinMEditFieldValueChanged(app, event)
            app.CD_0 = app.CyclodextrinMEditField.Value;
        end

        % Value changed function: AssociationConstKMhrEditField
        function AssociationConstKMhrEditFieldValueChanged(app, event)
            app.K = app.AssociationConstKMhrEditField.Value;
        end

        % Value changed function: DissociationConstk21hrEditField
        function DissociationConstk21hrEditFieldValueChanged(app, event)
            app.k2 = app.DissociationConstk21hrEditField.Value;
        end

        % Value changed function: DiffusionCoeffDm2hrEditField
        function DiffusionCoeffDm2hrEditFieldValueChanged(app, event)
            app.D = app.DiffusionCoeffDm2hrEditField.Value;  
        end

        % Value changed function: DurationhrEditField
        function DurationhrEditFieldValueChanged(app, event)
            app.hrs = app.DurationhrEditField.Value;
        end

        % Value changed function: ResolutionSpinner
        function ResolutionSpinnerValueChanged(app, event)
            app.res = app.ResolutionSpinner.Value; 
        end

        % Value changed function: CumulativeReleaseProfileCheckBox
        function CumulativeReleaseProfileCheckBoxValueChanged(app, event)
            app.Figure_1 = app.CumulativeReleaseProfileCheckBox.Value;   
        end

        % Value changed function: FractionalReleaseProfileCheckBox
        function FractionalReleaseProfileCheckBoxValueChanged(app, event)
            app.Figure_2 = app.FractionalReleaseProfileCheckBox.Value;    
        end

        % Value changed function: ChangeinReleaseRateCheckBox
        function ChangeinReleaseRateCheckBoxValueChanged(app, event)
            app.Figure_3 = app.ChangeinReleaseRateCheckBox.Value;   
        end

        % Value changed function: 
        % ChangeinFractionalReleaseProfileCheckBox
        function ChangeinFractionalReleaseProfileCheckBoxValueChanged(app, event)
            app.Figure_4 = app.ChangeinFractionalReleaseProfileCheckBox.Value;  
        end

        % Value changed function: 
        % DirectionalReleaseProfileheatmapCheckBox
        function DirectionalReleaseProfileheatmapCheckBoxValueChanged(app, event)
            app.Figure_5 = app.DirectionalReleaseProfileheatmapCheckBox.Value;     
        end

        % Value changed function: ReleaseProfileAnimationCheckBox
        function ReleaseProfileAnimationCheckBoxValueChanged(app, event)
            app.Animation = app.ReleaseProfileAnimationCheckBox.Value;
        end

        % Value changed function: LengthxmEditField
        function LengthxmEditFieldValueChanged(app, event)
            app.X = app.LengthxmEditField.Value; 
        end

        % Callback function
        function WidthymEditFieldValueChanged(app, event)
            app.Y = app.WidthymEditField.Value;
        end

        % Value changed function: UploadDataCheckBox
        function UploadDataCheckBoxValueChanged(app, event)
            app.UploadData = app.UploadDataCheckBox.Value;  
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
        % If any variables are not changed, use default values
            if numel(app.Drug_0) ~= 1
                app.Drug_0 = 0.2;
            end
            
            if numel(app.CD_0) ~= 1
                app.CD_0 = 0.2;
            end         
            
            if numel(app.K) ~= 1
                app.K = 1.5;
            end      

            if numel(app.k2) ~= 1
                app.k2 = 0.8;
            end  
            
            if numel(app.D) ~= 1
                app.D = 2e-7;
            end  
            
            if numel(app.hrs) ~= 1
                app.hrs = 30;
            end
            
            if numel(app.res) ~= 1
                app.res = 5;
            end
   
            if numel(app.X) ~= 1
                app.X = 0.005;
            end
            
            if numel(app.Y) ~= 1
                app.Y = 0.005;
            end
            
            if numel(app.Z) ~= 1
                app.Z = 0.005;
            end
            
            % Default values for control of progression
            choice = 1;
            
            % If the simulation resolution < 50 --> prompt warning
            if app.res < 50
               answer = questdlg('Resolutions below 50 may result in less accurate results (i.e. not all drug being released). Do you wish to continue?',...
                    'Warning','OK','Cancel','Cancel');

                % Handle response
                switch answer
                    case 'OK'
                        choice = 1;
                    case 'Cancel'
                        choice = 0;
                end
            end
            
            % If the user wants to see the animation and the resolution < 2
            %  --> prompt warning
            if numel(app.Animation) == 1 && app.res < 2
                warndlg('A resolution of 2 or higher is required for this feature',...
                    'Warning');
                app.Animation = [];
            end
            
            % Only run the app if the user presses 'OK' on warnings 
            if choice == 1
                % Plug parametere values into MoL_Script       
                [app.time,app.release,app.output] = MoL_Script_3D_Prism(app.X,...
                    app.Y,app.Z,app.Drug_0,app.CD_0,app.K,app.k2,app.D,app.hrs,...
                    app.res,app.Figure_1,app.Figure_2,app.Figure_3,app.Figure_4,...
                    app.Figure_5,app.Figure_6,app.Animation,app.UploadData);
            
                % Plot cumulative release profile
                plot(app.UIAxes, app.time, app.release)  
                % Return output text
                app.OutputTextArea.Value = app.output;
            end 
        end

        % Value changed function: HeightzmEditField
        function HeightzmEditFieldValueChanged(app, event)
            app.Z = app.HeightzmEditField.Value; 
        end

        % Value changed function: WidthymEditField
        function WidthymEditFieldValueChanged2(app, event)
            app.Y = app.WidthymEditField.Value; 
        end

        % Value changed function: DGradientsCheckBox
        function DGradientsCheckBoxValueChanged(app, event)
            app.Figure_6 = app.DGradientsCheckBox.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DPrismxyzdirectionsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {379, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DPrismxyzdirectionsUIFigure = uifigure('Visible', 'off');
            app.DPrismxyzdirectionsUIFigure.Name = '3D Prism (x,y,z - directions)';
            app.DPrismxyzdirectionsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DPrismxyzdirectionsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LengthxmEditFieldLabel = uilabel(app.LeftPanel);
            app.LengthxmEditFieldLabel.Text = 'Length (x, m)';
            app.LengthxmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LengthxmEditField.Limits = [0 Inf];
            app.LengthxmEditField.ValueChangedFcn = createCallbackFcn(app, @LengthxmEditFieldValueChanged, true);
            app.LengthxmEditField.Value = 0.005;
            app.AssociationConstKMhrEditFieldLabel = uilabel(app.LeftPanel);
            app.AssociationConstKMhrEditFieldLabel.Text = 'Association Const (K, 1/M)';
            app.AssociationConstKMhrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AssociationConstKMhrEditField.Limits = [0 Inf];
            app.AssociationConstKMhrEditField.ValueChangedFcn = createCallbackFcn(app, @AssociationConstKMhrEditFieldValueChanged, true);
            app.AssociationConstKMhrEditField.Value = 1.5;
            app.DissociationConstk21hrEditFieldLabel = uilabel(app.LeftPanel);
            app.DissociationConstk21hrEditFieldLabel.Text = 'Dissociation Const (k2, 1/hr)';
            app.DissociationConstk21hrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DissociationConstk21hrEditField.Limits = [0 Inf];
            app.DissociationConstk21hrEditField.ValueChangedFcn = createCallbackFcn(app, @DissociationConstk21hrEditFieldValueChanged, true);
            app.DissociationConstk21hrEditField.Value = 0.8;
            app.DiffusionCoeffDm2hrEditFieldLabel = uilabel(app.LeftPanel);
            app.DiffusionCoeffDm2hrEditFieldLabel.Text = 'Diffusion Coeff (D, m^2/hr)';
            app.DiffusionCoeffDm2hrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DiffusionCoeffDm2hrEditField.Limits = [0 Inf];
            app.DiffusionCoeffDm2hrEditField.ValueChangedFcn = createCallbackFcn(app, @DiffusionCoeffDm2hrEditFieldValueChanged, true);
            app.DiffusionCoeffDm2hrEditField.Value = 2e-07;
            app.DrugMEditFieldLabel = uilabel(app.LeftPanel);
            app.DrugMEditFieldLabel.Text = '[Drug] (M)';
            app.DrugMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DrugMEditField.Limits = [0 Inf];
            app.DrugMEditField.ValueChangedFcn = createCallbackFcn(app, @DrugMEditFieldValueChanged, true);
            app.DrugMEditField.Value = 0.2;
            app.CyclodextrinMEditFieldLabel = uilabel(app.LeftPanel);
            app.CyclodextrinMEditFieldLabel.Text = '[Cyclodextrin] (M)';
            app.CyclodextrinMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CyclodextrinMEditField.Limits = [0 Inf];
            app.CyclodextrinMEditField.ValueChangedFcn = createCallbackFcn(app, @CyclodextrinMEditFieldValueChanged, true);
            app.CyclodextrinMEditField.Value = 0.2;
            app.DurationhrEditFieldLabel = uilabel(app.LeftPanel);
            app.DurationhrEditFieldLabel.Text = 'Duration (hr)';
            app.DurationhrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DurationhrEditField.Limits = [0 Inf];
            app.DurationhrEditField.ValueChangedFcn = createCallbackFcn(app, @DurationhrEditFieldValueChanged, true);
            app.DurationhrEditField.Value = 30;
            app.ResolutionSpinnerLabel = uilabel(app.LeftPanel);
            app.ResolutionSpinnerLabel.Text = 'Resolution';
            app.ResolutionSpinner = uispinner(app.LeftPanel);
            app.ResolutionSpinner.Limits = [0 Inf];
            app.ResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @ResolutionSpinnerValueChanged, true);
            app.ResolutionSpinner.Value = 5;
            app.InputParametersLabel = uilabel(app.LeftPanel);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.FiguresLabel = uilabel(app.LeftPanel);
            app.FiguresLabel.Text = 'Figures';
            app.DirectionalReleaseProfileheatmapCheckBox = uicheckbox(app.LeftPanel);
            app.DirectionalReleaseProfileheatmapCheckBox.ValueChangedFcn = createCallbackFcn(app, @DirectionalReleaseProfileheatmapCheckBoxValueChanged, true);
            app.DirectionalReleaseProfileheatmapCheckBox.Text = 'Directional Release Profile (heatmap) ';
            app.ReleaseProfileAnimationCheckBox = uicheckbox(app.LeftPanel);
            app.ReleaseProfileAnimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReleaseProfileAnimationCheckBoxValueChanged, true);
            app.ReleaseProfileAnimationCheckBox.Text = 'Release Profile Animation';
            app.ChangeinFractionalReleaseProfileCheckBox = uicheckbox(app.LeftPanel);
            app.ChangeinFractionalReleaseProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeinFractionalReleaseProfileCheckBoxValueChanged, true);
            app.ChangeinFractionalReleaseProfileCheckBox.Text = 'Change in Fractional Release Profile';
            app.UploadDataCheckBox = uicheckbox(app.LeftPanel);
            app.UploadDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @UploadDataCheckBoxValueChanged, true);
            app.UploadDataCheckBox.Text = 'Upload Data';
            app.RunButton = uibutton(app.LeftPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.HeightzmEditFieldLabel = uilabel(app.LeftPanel);
            app.HeightzmEditFieldLabel.Text = 'Height (z, m)';
            app.HeightzmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HeightzmEditField.Limits = [0 Inf];
            app.HeightzmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightzmEditFieldValueChanged, true);
            app.HeightzmEditField.Value = 0.005;
            app.WidthymEditFieldLabel = uilabel(app.LeftPanel);
            app.WidthymEditFieldLabel.Text = 'Width (y, m)';
            app.WidthymEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WidthymEditField.Limits = [0 Inf];
            app.WidthymEditField.ValueChangedFcn = createCallbackFcn(app, @WidthymEditFieldValueChanged2, true);
            app.WidthymEditField.Value = 0.005;
            app.DGradientsCheckBox = uicheckbox(app.LeftPanel);
            app.DGradientsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DGradientsCheckBoxValueChanged, true);
            app.DGradientsCheckBox.Text = ' 3D Gradients';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Cumulative Release Profile')
            xlabel(app.UIAxes, 'Time (hr)')
            ylabel(app.UIAxes, 'Molar Drug (M)')
            app.OutputTextAreaLabel = uilabel(app.RightPanel);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.RightPanel);
            app.CumulativeReleaseProfileCheckBox = uicheckbox(app.DPrismxyzdirectionsUIFigure);
            app.CumulativeReleaseProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @CumulativeReleaseProfileCheckBoxValueChanged, true);
            app.CumulativeReleaseProfileCheckBox.Text = 'Cumulative Release Profile';
            app.FractionalReleaseProfileCheckBox = uicheckbox(app.DPrismxyzdirectionsUIFigure);
            app.FractionalReleaseProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @FractionalReleaseProfileCheckBoxValueChanged, true);
            app.FractionalReleaseProfileCheckBox.Text = 'Fractional Release Profile';
            app.ChangeinReleaseRateCheckBox = uicheckbox(app.DPrismxyzdirectionsUIFigure);
            app.ChangeinReleaseRateCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeinReleaseRateCheckBoxValueChanged, true);
            app.ChangeinReleaseRateCheckBox.Text = 'Change in Release Rate';
            % Show the figure after all components are created
            app.DPrismxyzdirectionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Prism_3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DPrismxyzdirectionsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DPrismxyzdirectionsUIFigure)
        end
    end
end